classdef spy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SeleccionaotomalafotodereferenciaLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        TomaFotodeReferenciaButton  matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        RightPanel                  matlab.ui.container.Panel
        LimpiarButton               matlab.ui.control.Button
        AlertaLabel                 matlab.ui.control.Label
        UserGuideLabel              matlab.ui.control.Label
        InfoLabel                   matlab.ui.control.Label
        EmpezarButton               matlab.ui.control.Button
        HUPROSYHumanProtectionSystemLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        reference_image = []; % Description
        keep_spy = true;
        rand_num = rand(1);
    end
    
    methods (Access = private)
                                     
        function [highlighted_img, alert] = Segmentation_Fn(app, img1, img2)

            img1BW = rgb2gray(img1);
            img2BW = rgb2gray(img2);
            
            imgDiff = abs(img2BW - img1BW);
         
            imgThresh = imgDiff > 8;
         
            imgFilled = bwareaopen(imgThresh, 15);
            
            imgBoth = imoverlay(img2,imgFilled,[1 0 0]);
            highlighted_img = imgBoth;

            imageStats = regionprops(imgFilled, 'MajorAxisLength');
            
            imgLengths = [imageStats.MajorAxisLength];
            idx = imgLengths > 150;
            imageStatsFinal = imageStats(idx);
    
            if ~isempty(imageStatsFinal)
                alert = true;
            else
                alert = false;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TomaFotodeReferenciaButton
        function TomaFotodeReferenciaButtonPushed(app, event)
            app.keep_spy = false;
            app.InfoLabel.Text = "Da clic en el botón de 'Empezar' para iniciar el monitoreo";
            app.AlertaLabel.Text = "";
            cam = webcam;
            app.reference_image = snapshot(cam);
            app.Image.ImageSource = app.reference_image;
            clear cam;
        end

        % Button pushed function: EmpezarButton
        function EmpezarButtonPushed(app, event)
            app.keep_spy = true;
            if isempty(app.reference_image)
                uialert(app.UIFigure, "Primero debes seleccionar la imagen de referencia", "Error");
            else
                app.InfoLabel.Text = "";
                % Empezar monitoreo
                cam = webcam;
                
                [a, fs] = audioread("alarm.wav");
                p = audioplayer(a, fs);

                seen_file_name = "seen_" + app.rand_num + ".avi";
                vidWriter = VideoWriter(convertStringsToChars(seen_file_name));
                open(vidWriter);
                while app.keep_spy
                   
                    curr_img = snapshot(cam);
                    [highlitedImg, alert] = app.Segmentation_Fn(curr_img, app.reference_image);
                    imshow(highlitedImg, 'Parent', app.UIAxes);
                    if alert 
                        app.AlertaLabel.Text = "ALERTA, HAY UN INTRUSO";
                        play(p);
                        writeVideo(vidWriter, curr_img);
                    else
                        app.AlertaLabel.Text = "";
                        stop(p);
                        app.rand_num = rand(1);
                    end
                    drawnow
                end
                cla(app.UIAxes);
              
            end 
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
        [filename, pathname] = uigetfile({ ...  
            '*.jpg; *.JPG; *.jpeg; *.JPEG; *.img; *.IMG; *.tif; *.TIF; *.tiff, *.TIFF','Supported Files (*.jpg,*.img,*.tiff,)'; ...
            '*.jpg','jpg Files (*.jpg)';...
            '*.JPG','JPG Files (*.JPG)';...
            '*.jpeg','jpeg Files (*.jpeg)';...
            '*.JPEG','JPEG Files (*.JPEG)';...
            '*.img','img Files (*.img)';...
            '*.IMG','IMG Files (*.IMG)';...
            '*.tif','tif Files (*.tif)';...
            '*.TIF','TIF Files (*.TIF)';...
            '*.tiff','tiff Files (*.tiff)';...
            '*.TIFF','TIFF Files (*.TIFF)'...
        });
        if isequal(filename,0)
            uialert(app.UIFigure, "Selecciona algún archivo", "Error");
        else
            app.reference_image = imread(fullfile(pathname, filename));
            app.Image.ImageSource = app.reference_image;
            figure(app.UIFigure)
        end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.keep_spy = false;
            app.reference_image = [];
            app.InfoLabel.Text = "Da clic en el botón de 'Empezar' para iniciar el monitoreo";
            app.Image.ImageSource = "";
            app.AlertaLabel.Text = "";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.TomaFotodeReferenciaButton = uibutton(app.LeftPanel, 'push');
            app.TomaFotodeReferenciaButton.ButtonPushedFcn = createCallbackFcn(app, @TomaFotodeReferenciaButtonPushed, true);
            app.TomaFotodeReferenciaButton.Text = 'Toma Foto de Referencia';
            app.Label = uilabel(app.LeftPanel);
            app.SeleccionaotomalafotodereferenciaLabel = uilabel(app.LeftPanel);
            app.SeleccionaotomalafotodereferenciaLabel.Text = 'Selecciona o toma la foto de referencia';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = 1;
            app.UIAxes.YTick = 0;
            app.UIAxes.ZTick = 0;
            app.HUPROSYHumanProtectionSystemLabel = uilabel(app.RightPanel);
            app.HUPROSYHumanProtectionSystemLabel.Text = 'HUPROSY - Human Protection System';
            app.EmpezarButton = uibutton(app.RightPanel, 'push');
            app.EmpezarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezarButtonPushed, true);
            app.EmpezarButton.Text = 'Empezar';
            app.InfoLabel = uilabel(app.RightPanel);
            app.InfoLabel.Text = 'Da clic en el botón de ''Empezar'' para iniciar el monitoreo';
            app.UserGuideLabel = uilabel(app.RightPanel);
            app.UserGuideLabel.Text = 'Lee nuestro manual de usuario en huprosy.vercel.app';
            app.AlertaLabel = uilabel(app.RightPanel);
            app.AlertaLabel.Text = '';
            app.LimpiarButton = uibutton(app.RightPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end